classdef NEOSS_Utility < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NEOSS_UtilityUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        UITableParam           matlab.ui.control.Table
        EnregistrerButton      matlab.ui.control.Button
        ChargerButton          matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
    end

    properties (Access = public)
        ListeDatNEOSS={"RPupille Totale (""CS"")";...
            "Résolution Totale (""CS"")";...
            "Résolution Sous pupille (""TP"")";...
            "Rpupille Sous pupille (""TP"")";...
            "lambda";...
            "Nombre de SSPP";...
            "sigma Pondération Pixels";...
            "mismatch";...
            "Fichier de position des sous pupilles";...
            "Polynomes décrivant l'instrument 'Z' ou 'L'";...
            "Polynome décrivant le miroir 'Z' ou 'L'";...
            "premier Indice alignement 0 pour PISTON ect.";...
            "dernier Indice alignement 0 pour PISTON ect.";...
            "premier Indice du modèle 'CS'";...
            "dernier Indice du modèle 'CS'";...
            "premier Indice de la carte de biais SSPP 'TP'";...
            "dernier Indice de la carte de biais SSPP 'TP'";...
            "Limite Calcul Carte Moyenne";...
            "Carte Supportage (Booléen)";...
            "Chemin de la carte supportage";...
            "Coordonnées (IDOINE ou Polaire)"};
    end


    methods (Access = private)
        
        function LoadPars(app,path)
            Dat=readcell(path);
            Dat=Dat(1:21,:);
            Dat(:,2)=Dat(1:21,1); %on se limite a 21 paramètres
            Dat(:,1)=app.ListeDatNEOSS;
            app.UITableParam.Data=cell2table(Dat);
            app.UITableParam.ColumnEditable=[false,true];
            app.paramfile=path;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ParamPath)
            %initialisation des variables utilisées dans Neoss_Utility.
            if ~isempty(ParamPath)
                LoadPars(app,ParamPath)
            else
                Dat=app.ListeDatNEOSS;
                Dat(:,2)={0;0;0;0;632.8;0;12;0;'Position_sspp.txt';'L';'L';0;0;0;0;0;0;0.96;0;'';'IDOINE'};
                app.UITableParam.Data=cell2table(Dat);
                app.UITableParam.ColumnEditable=[false,true];
            end

        end

        % Button pushed function: EnregistrerButton
        function EnregistrerButtonPushed(app, event)
            %sauvegarde les paramètres saisies dans la partie paramètre
            app.NEOSS_UtilityUIFigure.Pointer='watch';
            [file,folder]=uiputfile({'*.txt','Fichier de paramètre NEOSS'},'Choisir le fichier de paramètres');
            figure(app.NEOSS_UtilityUIFigure)
            DatO=app.UITableParam.Data;
            Dat(:,1)=DatO(:,2)
            Dat(:,2)=app.ListeDatNEOSS;
            writecell(table2cell(Dat),[folder,file],"Delimiter",";")
            app.NEOSS_UtilityUIFigure.Pointer='arrow';
        end

        % Button pushed function: ChargerButton
        function ChargerButtonPushed(app, event)
            app.paramfile='';
            [file,folder]=uigetfile({'*.txt','Fichier de paramètre NEOSS'},'Choisir le fichier de paramètres','multiselect','off');
            figure(app.NEOSS_UtilityUIFigure)
            LoadPars(app,[folder,file])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NEOSS_UtilityUIFigure = uifigure('Visible', 'off');
            app.NEOSS_UtilityUIFigure.Name = 'NEOSS';
            app.GridLayout = uigridlayout(app.NEOSS_UtilityUIFigure);
            app.ChargerButton = uibutton(app.GridLayout, 'push');
            app.ChargerButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerButtonPushed, true);
            app.ChargerButton.Text = 'Charger';
            app.EnregistrerButton = uibutton(app.GridLayout, 'push');
            app.EnregistrerButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerButtonPushed, true);
            app.EnregistrerButton.Text = 'Enregistrer';
            app.UITableParam = uitable(app.GridLayout);
            app.UITableParam.ColumnName = {'Indices'; 'Valeurs'};
            app.UITableParam.RowName = {};
            % Show the figure after all components are created
            app.NEOSS_UtilityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NEOSS_Utility(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NEOSS_UtilityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NEOSS_UtilityUIFigure)
        end
    end
end